classdef window_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout12_4             matlab.ui.container.GridLayout
        smoothingCheckBox          matlab.ui.control.CheckBox
        FilterTypeDropDown_2       matlab.ui.control.DropDown
        FilterTypeDropDown_2Label  matlab.ui.control.Label
        GridLayout9                matlab.ui.container.GridLayout
        anchor4_orientation        matlab.ui.control.Spinner
        Anchor4DropDown            matlab.ui.control.DropDown
        Anchor4DropDownLabel       matlab.ui.control.Label
        GridLayout8                matlab.ui.container.GridLayout
        anchor3_orientation        matlab.ui.control.Spinner
        Anchor3DropDown            matlab.ui.control.DropDown
        Anchor3DropDownLabel       matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        anchor2_orientation        matlab.ui.control.Spinner
        Anchor2DropDown            matlab.ui.control.DropDown
        Anchor2DropDownLabel       matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        anchor1_orientation        matlab.ui.control.Spinner
        Anchor1DropDown            matlab.ui.control.DropDown
        Anchor1Label               matlab.ui.control.Label
        RealTimeAPPLabel           matlab.ui.control.Label
        realtimebutton             matlab.ui.control.Button
        map_axe                    matlab.ui.control.UIAxes
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout12_3             matlab.ui.container.GridLayout
        GeometryDropDown           matlab.ui.control.DropDown
        GeometryDropDownLabel      matlab.ui.control.Label
        GridLayout12_2             matlab.ui.container.GridLayout
        FilterTypeDropDown         matlab.ui.control.DropDown
        FilterTypeDropDownLabel    matlab.ui.control.Label
        GridLayout12               matlab.ui.container.GridLayout
        SmoothingDropDown          matlab.ui.control.DropDown
        SmoothingDropDownLabel     matlab.ui.control.Label
        GridLayout11               matlab.ui.container.GridLayout
        Image2                     matlab.ui.control.Image
        SimulationLabel            matlab.ui.control.Label
        GridLayout10               matlab.ui.container.GridLayout
        X_centrSpinner_2           matlab.ui.control.Spinner
        Y_centerLabel              matlab.ui.control.Label
        X_centerSpinner            matlab.ui.control.Spinner
        X_centrLabel               matlab.ui.control.Label
        ASpinner                   matlab.ui.control.Spinner
        ALabel                     matlab.ui.control.Label
        BSpinner                   matlab.ui.control.Spinner
        BLabel                     matlab.ui.control.Label
        Simulationbutton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        ports = serialportlist; % Description
        callingApp % Description
        x_0
        Q
        X_anchor;
        Y_anchor;
        ref_point;
        sigma_theta;
        filter_type;
        smoothing;
        courbe_type;
        a;
        b;
        f;
        orientation;
        label_anchor=["R1" "R2" "R3" "R4"];
        X_center;
        Y_center;
        port1;port2;port3;port4;
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function map_plot(app)
            app.anchor1_orientation.Value=app.orientation(1);
            app.anchor2_orientation.Value=app.orientation(2);
            app.anchor3_orientation.Value=app.orientation(3);
            app.anchor4_orientation.Value=app.orientation(4);
            cla(app.map_axe);
            hold(app.map_axe,"on")
            for i = 1:size(app.ref_point, 1)
            x_i = app.ref_point(i, 1);
            y_i = app.ref_point(i, 2);
            dx=(app.X_anchor)*cos(deg2rad(app.orientation(i)))/5;
            dy=(app.X_anchor)*sin(deg2rad(app.orientation(i)))/5;
            % Plot the point as a yellow scatter plot with a label, e.g., 'R1', 'R2', ...
            scatter(app.map_axe, ...
                    x_i, y_i, 100, ...
                    'filled', ...
                    'MarkerFaceColor', 'yellow', ...
                    'LineWidth', 3);
            text(app.map_axe, x_i -dx, y_i -dy, app.label_anchor(i));
            
            quiver(app.map_axe, ...
                   x_i, y_i, ...
                   dx, dy, 0, 'LineWidth', 2, 'MaxHeadSize', 0.5);

            end

            xlim(app.map_axe,[-app.X_anchor*0.2,app.X_anchor*1.2]);
            ylim(app.map_axe,[-app.Y_anchor*0.2,app.Y_anchor*1.2]);
            grid(app.map_axe,"on");
            hold(app.map_axe,"off");

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, x_0, Q, X_anchor, Y_anchor, sigma_theta, f, orientation)
            app.callingApp=caller;
            app.x_0=x_0;
            app.Q=Q;
            app.X_anchor=X_anchor;
            app.Y_anchor=Y_anchor;
            app.sigma_theta=sigma_theta;
            app.f=f;
            app.orientation=orientation;
            app.Anchor1DropDown.Items=app.ports;
            app.Anchor2DropDown.Items=app.ports;
            app.Anchor3DropDown.Items=app.ports;
            app.Anchor4DropDown.Items=app.ports;
            app.ref_point=[0 0; X_anchor 0; X_anchor Y_anchor; 0 Y_anchor];
            app.X_center=app.X_centerSpinner.Value;
            app.Y_center=app.X_centerSpinner.Value;
            app.map_plot();

        end

        % Drop down opening function: Anchor1DropDown
        function Anchor1DropDownDropDownOpening(app, event)
            app.Anchor1DropDown.Items=app.ports;
        end

        % Drop down opening function: Anchor2DropDown
        function Anchor2DropDownOpening(app, event)
             app.Anchor2DropDown.Items=app.ports;
        end

        % Drop down opening function: Anchor3DropDown
        function Anchor3DropDownOpening(app, event)
             app.Anchor3DropDown.Items=app.ports;
        end

        % Drop down opening function: Anchor4DropDown
        function Anchor4DropDownOpening(app, event)
             app.Anchor4DropDown.Items=app.ports;
        end

        % Button pushed function: Simulationbutton
        function SimulationbuttonPushed(app, event)
            try
            app.filter_type=app.FilterTypeDropDown.Value;
            app.courbe_type=app.GeometryDropDown.Value;
            app.smoothing=app.SmoothingDropDown.Value;
            app.a=app.ASpinner.Value;
            app.b=app.BSpinner.Value;
            Filter_Performance_Comparison(app);
            catch ex
                errordlg(ex.message,ex.identifier);
            end
        end

        % Button pushed function: realtimebutton
        function realtimebuttonButtonPushed(app, event)
           try
                app.port1=app.Anchor1DropDown.Value;
                app.port2=app.Anchor2DropDown.Value;
                app.port3=app.Anchor3DropDown.Value;
                app.port4=app.Anchor4DropDown.Value;
                app.filter_type=app.FilterTypeDropDown_2.Value;
                filter_Algorithm_Selector(app);
           catch ex
               errordlg(ex.message,ex.identifier);
           end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            try 
                window_2(app.x_0,app.Q,app.X_anchor,app.Y_anchor,app.sigma_theta,app.f);
                app.delete;
            catch ex
                errordlg(ex.message,ex.identifier);
            end
        end

        % Value changed function: anchor1_orientation
        function anchor1_orientationValueChanged(app, event)
            app.orientation(1) = app.anchor1_orientation.Value;
            app.map_plot();
            
        end

        % Value changed function: anchor2_orientation
        function anchor2_orientationValueChanged(app, event)
            app.orientation(2) = app.anchor2_orientation.Value;
            app.map_plot();
            
        end

        % Value changed function: anchor3_orientation
        function anchor3_orientationValueChanged(app, event)
            app.orientation(3) = app.anchor3_orientation.Value;
            app.map_plot();
        end

        % Value changed function: anchor4_orientation
        function anchor4_orientationValueChanged(app, event)
            app.orientation(4) = app.anchor4_orientation.Value;
            app.map_plot();
        end

        % Value changed function: Anchor1DropDown
        function Anchor1DropDownValueChanged(app, event)
            app.label_anchor(1) = app.Anchor1DropDown.Value;
            app.map_plot();
            
        end

        % Value changed function: Anchor2DropDown
        function Anchor2DropDownValueChanged(app, event)
           app.label_anchor(2) = app.Anchor2DropDown.Value;
           app.map_plot();
            
        end

        % Value changed function: Anchor3DropDown
        function Anchor3DropDownValueChanged(app, event)
            app.label_anchor(3) = app.Anchor3DropDown.Value;
            app.map_plot();
        end

        % Value changed function: Anchor4DropDown
        function Anchor4DropDownValueChanged(app, event)
            app.label_anchor(4) = app.Anchor4DropDown.Value;
            app.map_plot();
            
        end

        % Value changed function: X_centerSpinner
        function X_centerSpinnerValueChanged(app, event)
            app.X_center = app.X_centerSpinner.Value;
            
        end

        % Value changed function: X_centrSpinner_2
        function X_centrSpinner_2ValueChanged(app, event)
            app.Y_center= app.X_centrSpinner_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Simulationbutton = uibutton(app.GridLayout2, 'push');
            app.Simulationbutton.ButtonPushedFcn = createCallbackFcn(app, @SimulationbuttonPushed, true);
            app.Simulationbutton.Text = 'CONTINUE';
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.BLabel = uilabel(app.GridLayout10);
            app.BLabel.Interpreter = 'latex';
            app.BLabel.Text = '$B$';
            app.BSpinner = uispinner(app.GridLayout10);
            app.ALabel = uilabel(app.GridLayout10);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = '$A$';
            app.ASpinner = uispinner(app.GridLayout10);
            app.X_centrLabel = uilabel(app.GridLayout10);
            app.X_centrLabel.Interpreter = 'latex';
            app.X_centrLabel.Text = '$X_{center}$';
            app.X_centerSpinner = uispinner(app.GridLayout10);
            app.X_centerSpinner.ValueChangedFcn = createCallbackFcn(app, @X_centerSpinnerValueChanged, true);
            app.Y_centerLabel = uilabel(app.GridLayout10);
            app.Y_centerLabel.Interpreter = 'latex';
            app.Y_centerLabel.Text = '$Y_{center}$';
            app.X_centrSpinner_2 = uispinner(app.GridLayout10);
            app.X_centrSpinner_2.ValueChangedFcn = createCallbackFcn(app, @X_centrSpinner_2ValueChanged, true);
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SimulationLabel = uilabel(app.GridLayout11);
            app.SimulationLabel.Text = 'Simulation ';
            app.Image2 = uiimage(app.GridLayout11);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'image', 'back.png');
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.SmoothingDropDownLabel = uilabel(app.GridLayout12);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.GridLayout12);
            app.SmoothingDropDown.Items = {'Without smoothing', 'On line smoothing', 'RTS smoothing'};
            app.SmoothingDropDown.Value = 'Without smoothing';
            app.GridLayout12_2 = uigridlayout(app.GridLayout2);
            app.FilterTypeDropDownLabel = uilabel(app.GridLayout12_2);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.GridLayout12_2);
            app.FilterTypeDropDown.Items = {'Unscented Kalman filter', 'Extended Kalman filter', 'Comparison between the two filters'};
            app.FilterTypeDropDown.Value = 'Unscented Kalman filter';
            app.GridLayout12_3 = uigridlayout(app.GridLayout2);
            app.GeometryDropDownLabel = uilabel(app.GridLayout12_3);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.GridLayout12_3);
            app.GeometryDropDown.Items = {'Ellipse', 'Square', 'Triangle', 'Hexagonal'};
            app.GeometryDropDown.Value = 'Ellipse';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.map_axe = uiaxes(app.GridLayout3);
            title(app.map_axe, 'Map view ')
            zlabel(app.map_axe, 'Z')
            app.realtimebutton = uibutton(app.GridLayout3, 'push');
            app.realtimebutton.ButtonPushedFcn = createCallbackFcn(app, @realtimebuttonButtonPushed, true);
            app.realtimebutton.Text = 'CONTUNIE';
            app.RealTimeAPPLabel = uilabel(app.GridLayout3);
            app.RealTimeAPPLabel.Text = 'Real Time APP';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Anchor1Label = uilabel(app.GridLayout6);
            app.Anchor1Label.Text = 'Anchor 1';
            app.Anchor1DropDown = uidropdown(app.GridLayout6);
            app.Anchor1DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Anchor1DropDownDropDownOpening, true);
            app.Anchor1DropDown.ValueChangedFcn = createCallbackFcn(app, @Anchor1DropDownValueChanged, true);
            app.anchor1_orientation = uispinner(app.GridLayout6);
            app.anchor1_orientation.ValueChangedFcn = createCallbackFcn(app, @anchor1_orientationValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Anchor2DropDownLabel = uilabel(app.GridLayout7);
            app.Anchor2DropDownLabel.Text = 'Anchor 2';
            app.Anchor2DropDown = uidropdown(app.GridLayout7);
            app.Anchor2DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Anchor2DropDownOpening, true);
            app.Anchor2DropDown.ValueChangedFcn = createCallbackFcn(app, @Anchor2DropDownValueChanged, true);
            app.anchor2_orientation = uispinner(app.GridLayout7);
            app.anchor2_orientation.ValueChangedFcn = createCallbackFcn(app, @anchor2_orientationValueChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.Anchor3DropDownLabel = uilabel(app.GridLayout8);
            app.Anchor3DropDownLabel.Text = 'Anchor 3';
            app.Anchor3DropDown = uidropdown(app.GridLayout8);
            app.Anchor3DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Anchor3DropDownOpening, true);
            app.Anchor3DropDown.ValueChangedFcn = createCallbackFcn(app, @Anchor3DropDownValueChanged, true);
            app.anchor3_orientation = uispinner(app.GridLayout8);
            app.anchor3_orientation.ValueChangedFcn = createCallbackFcn(app, @anchor3_orientationValueChanged, true);
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.Anchor4DropDownLabel = uilabel(app.GridLayout9);
            app.Anchor4DropDownLabel.Text = 'Anchor 4';
            app.Anchor4DropDown = uidropdown(app.GridLayout9);
            app.Anchor4DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Anchor4DropDownOpening, true);
            app.Anchor4DropDown.ValueChangedFcn = createCallbackFcn(app, @Anchor4DropDownValueChanged, true);
            app.anchor4_orientation = uispinner(app.GridLayout9);
            app.anchor4_orientation.ValueChangedFcn = createCallbackFcn(app, @anchor4_orientationValueChanged, true);
            app.GridLayout12_4 = uigridlayout(app.GridLayout5);
            app.FilterTypeDropDown_2Label = uilabel(app.GridLayout12_4);
            app.FilterTypeDropDown_2Label.Text = 'Filter Type';
            app.FilterTypeDropDown_2 = uidropdown(app.GridLayout12_4);
            app.FilterTypeDropDown_2.Items = {'Unscented Kalman filter', 'Extended Kalman filter', 'Comparison between the two filters'};
            app.FilterTypeDropDown_2.Value = 'Unscented Kalman filter';
            app.smoothingCheckBox = uicheckbox(app.GridLayout12_4);
            app.smoothingCheckBox.Text = 'smoothing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end