classdef window_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        ContinueButton           matlab.ui.control.Button
        GridLayout_2             matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        nLabel                   matlab.ui.control.Label
        f_eSpinner               matlab.ui.control.Spinner
        f_eSpinnerLabel          matlab.ui.control.Label
        SeethemapexampleLabel_2  matlab.ui.control.Label
        SeethemapexampleLabel    matlab.ui.control.Label
        Sigma                    matlab.ui.control.Slider
        Covarianceofthenoiseq_1q_2q_3q_4Label  matlab.ui.control.Label
        xv_xyv_yLabel            matlab.ui.control.Label
        x0_input                 matlab.ui.control.EditField
        Q_input                  matlab.ui.control.EditField
        X_anchor_input           matlab.ui.control.NumericEditField
        Y_anchor_input           matlab.ui.control.NumericEditField
        X_anchorLabel            matlab.ui.control.Label
        Y_anchorLabel            matlab.ui.control.Label
        sigma_thetaLabel         matlab.ui.control.Label
        QLabel                   matlab.ui.control.Label
        X_0Label                 matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        MapExempleLabel          matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        OutPutApp % Description
        X0
        Q
        X_anchor
        Y_anchor
        sigma_thetha
        f
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x_0, Q, X_anchor, Y_anchor, sigma_theta, f)
            app.X0=x_0;
            app.Q=Q;
            app.X_anchor=X_anchor;
            app.Y_anchor=Y_anchor;
            app.sigma_thetha=sigma_theta;
            app.f=f;
            app.x0_input.Value=x_0;
            app.Q_input.Value=Q;
            app.X_anchor_input.Value=X_anchor;
            app.Y_anchor_input.Value=Y_anchor;
            app.Sigma.Value=sigma_theta;
            app.f_eSpinner.Value=f;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            try 
                app.X0=app.x0_input.Value;
                app.Q=app.Q_input.Value;
                app.X_anchor=app.X_anchor_input.Value;
                app.Y_anchor=app.Y_anchor_input.Value;
                app.sigma_thetha=app.Sigma.Value;
                app.f=app.f_eSpinner.Value;
                app.OutPutApp=window_3(app,app.X0, ...
                                   app.Q,app.X_anchor, ...
                                   app.Y_anchor,app.sigma_thetha,app.f,[90,90,-90,-90]);
                app.delete;
            catch ex
                errordlg(ex.message,ex.identifier);
            end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout_2);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image', 'map.png');
            app.MapExempleLabel = uilabel(app.GridLayout2);
            app.MapExempleLabel.Text = 'Map Exemple';
            app.GridLayout3 = uigridlayout(app.GridLayout_2);
            app.X_0Label = uilabel(app.GridLayout3);
            app.X_0Label.Interpreter = 'latex';
            app.X_0Label.Text = '$X_0$';
            app.QLabel = uilabel(app.GridLayout3);
            app.QLabel.Interpreter = 'latex';
            app.QLabel.Text = '$Q$';
            app.sigma_thetaLabel = uilabel(app.GridLayout3);
            app.sigma_thetaLabel.Interpreter = 'latex';
            app.sigma_thetaLabel.Text = '$\sigma_{\theta}$';
            app.Y_anchorLabel = uilabel(app.GridLayout3);
            app.Y_anchorLabel.Interpreter = 'latex';
            app.Y_anchorLabel.Text = '$Y_{anchor}$';
            app.X_anchorLabel = uilabel(app.GridLayout3);
            app.X_anchorLabel.Interpreter = 'latex';
            app.X_anchorLabel.Text = '$X_{anchor}$';
            app.Y_anchor_input = uieditfield(app.GridLayout3, 'numeric');
            app.X_anchor_input = uieditfield(app.GridLayout3, 'numeric');
            app.Q_input = uieditfield(app.GridLayout3, 'text');
            app.x0_input = uieditfield(app.GridLayout3, 'text');
            app.xv_xyv_yLabel = uilabel(app.GridLayout3);
            app.xv_xyv_yLabel.Interpreter = 'latex';
            app.xv_xyv_yLabel.Text = '$[x,v_x,y,v_y]$';
            app.Covarianceofthenoiseq_1q_2q_3q_4Label = uilabel(app.GridLayout3);
            app.Covarianceofthenoiseq_1q_2q_3q_4Label.Interpreter = 'latex';
            app.Covarianceofthenoiseq_1q_2q_3q_4Label.Text = {'Covariance of '; 'the noise'; '$[q_1,q_2,q_3,q_4]$'};
            app.Sigma = uislider(app.GridLayout3);
            app.Sigma.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Sigma.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.Sigma.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SeethemapexampleLabel = uilabel(app.GridLayout3);
            app.SeethemapexampleLabel.Text = {'See the map '; 'example'};
            app.SeethemapexampleLabel_2 = uilabel(app.GridLayout3);
            app.SeethemapexampleLabel_2.Text = {'See the map '; 'example'};
            app.f_eSpinnerLabel = uilabel(app.GridLayout3);
            app.f_eSpinnerLabel.Interpreter = 'latex';
            app.f_eSpinnerLabel.Text = '$f_e$';
            app.f_eSpinner = uispinner(app.GridLayout3);
            app.nLabel = uilabel(app.GridLayout3);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$=10^n$';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ContinueButton = uibutton(app.GridLayout4, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end